classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametersPanel                 matlab.ui.container.Panel
        MotorParametersPanel            matlab.ui.container.Panel
        RPM_motorSpinner                matlab.ui.control.Spinner
        RPMSpinnerLabel                 matlab.ui.control.Label
        K_motorSpinner                  matlab.ui.control.Spinner
        KSpinnerLabel                   matlab.ui.control.Label
        b_motorSpinner                  matlab.ui.control.Spinner
        bSpinner_2Label                 matlab.ui.control.Label
        J_motorSpinner                  matlab.ui.control.Spinner
        mSpinner_2Label_2               matlab.ui.control.Label
        L_motorSpinner                  matlab.ui.control.Spinner
        LSpinner_2Label                 matlab.ui.control.Label
        R_motorSpinner                  matlab.ui.control.Spinner
        RSpinner_2Label                 matlab.ui.control.Label
        BandBParametersPanel            matlab.ui.container.Panel
        StepAmplitude_b_and_bSpinner    matlab.ui.control.Spinner
        StepAmplitudeSpinnerLabel       matlab.ui.control.Label
        L_b_and_bSpinner                matlab.ui.control.Spinner
        LSpinnerLabel                   matlab.ui.control.Label
        R_b_and_bSpinner                matlab.ui.control.Spinner
        RSpinnerLabel                   matlab.ui.control.Label
        d_b_and_bSpinner                matlab.ui.control.Spinner
        dSpinnerLabel                   matlab.ui.control.Label
        g_b_and_bSpinner                matlab.ui.control.Spinner
        gSpinnerLabel                   matlab.ui.control.Label
        m_b_and_bSpinner                matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        CruiseParametersPanel           matlab.ui.container.Panel
        Speed_cruiseSpinner             matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        b_cruiseSpinner                 matlab.ui.control.Spinner
        bSpinnerLabel                   matlab.ui.control.Label
        m_cruiseSpinner                 matlab.ui.control.Spinner
        mSpinner_2Label                 matlab.ui.control.Label
        ResultsTable                    matlab.ui.control.Table
        OptimizationSettingsPanel       matlab.ui.container.Panel
        RunButton                       matlab.ui.control.Button
        MaxKdSpinner                    matlab.ui.control.Spinner
        MaxKdSpinnerLabel               matlab.ui.control.Label
        MaxKiSpinner                    matlab.ui.control.Spinner
        MaxKiSpinnerLabel               matlab.ui.control.Label
        MaxKpSpinner                    matlab.ui.control.Spinner
        MaxKpSpinnerLabel               matlab.ui.control.Label
        MaxIterationsSpinner            matlab.ui.control.Spinner
        MaxIterationsSpinnerLabel       matlab.ui.control.Label
        PopulationNumberofStarsSpinner  matlab.ui.control.Spinner
        PopulationNumberofStarsLabel    matlab.ui.control.Label
        SystemSelectionPanel            matlab.ui.container.Panel
        TypeofControllerDropDown        matlab.ui.control.DropDown
        TypeofControllerDropDownLabel   matlab.ui.control.Label
        SelectSystemDropDown            matlab.ui.control.DropDown
        SelectSystemDropDownLabel       matlab.ui.control.Label
        BlackHoleOptimizerLabel         matlab.ui.control.Label
        Cost                            matlab.ui.control.UIAxes
        System_Response                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ParamLabels = matlab.ui.control.Label.empty
        ParamFields = matlab.ui.control.NumericEditField.empty
        ParamNames cell = {}
    end

    methods (Access = private)

        function [VarMin, VarMax, gainNames] = getControllerInfo(app)

            controllerType = app.TypeofControllerDropDown.Value;

            maxKp = app.MaxKpSpinner.Value;
            maxKi = app.MaxKiSpinner.Value;
            maxKd = app.MaxKdSpinner.Value;

            % Basic validation
            if maxKp <= 0
                error('Max Kp must be greater than zero.');
            end

            switch controllerType
                case 'PI'
                    if maxKi <= 0
                        error('Max Ki must be greater than zero for PI control.');
                    end

                    VarMin = [0 0];
                    VarMax = [maxKp maxKi];
                    gainNames = {'Kp', 'Ki'};

                case 'PD'
                    if maxKd <= 0
                        error('Max Kd must be greater than zero for PD control.');
                    end

                    VarMin = [0 0];
                    VarMax = [maxKp maxKd];
                    gainNames = {'Kp', 'Kd'};

                case 'PID'
                    if maxKi <= 0 || maxKd <= 0
                        error('Max Ki and Max Kd must be greater than zero for PID control.');
                    end

                    VarMin = [0 0 0];
                    VarMax = [maxKp maxKi maxKd];
                    gainNames = {'Kp', 'Ki', 'Kd'};

                otherwise
                    error('Unknown controller type.');
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';
        end

        % Value changed function: SelectSystemDropDown
        function SelectSystemDropDownValueChanged(app, event)
            value = app.SelectSystemDropDown.Value;
            disp(value)

            app.BandBParametersPanel.Visible = 'off';
            app.CruiseParametersPanel.Visible = 'off';
            app.MotorParametersPanel.Visible = 'off';

            if strcmp(value, 'Ball & Beam')
                app.BandBParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Cruise Control')
                app.CruiseParametersPanel.Visible = 'on';

            elseif strcmp(value, 'Motor Speed Control')
                app.MotorParametersPanel.Visible = 'on';
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % ===== Read selected system and controller =====
            systemName     = app.SelectSystemDropDown.Value;
            controllerType = app.TypeofControllerDropDown.Value;

            % ===== Read optimization settings =====
            nPop  = app.PopulationNumberofStarsSpinner.Value;
            MaxIt = app.MaxIterationsSpinner.Value;

            % ===== Get controller bounds =====
            [~, VarMax, gainNames] = getControllerInfo(app);

            % ===== Read system parameters =====
            switch systemName

                case 'Ball & Beam'
                    params = {
                        'm', app.m_b_and_bSpinner.Value;
                        'R', app.R_b_and_bSpinner.Value;
                        'g', app.g_b_and_bSpinner.Value;
                        'L', app.L_b_and_bSpinner.Value;
                        'd', app.d_b_and_bSpinner.Value;
                        'step_amp', app.StepAmplitude_b_and_bSpinner.Value
                        };

                case 'Cruise Control'
                    params = {
                        'm', app.m_cruiseSpinner.Value;
                        'b', app.b_cruiseSpinner.Value;
                        'speed', app.Speed_cruiseSpinner.Value
                        };

                case 'Motor Speed Control'
                    params = {
                        'J', app.J_motorSpinner.Value;
                        'b', app.b_motorSpinner.Value;
                        'K', app.K_motorSpinner.Value;
                        'R', app.R_motorSpinner.Value;
                        'L', app.L_motorSpinner.Value;
                        'rpm', app.RPM_motorSpinner.Value
                        };

                otherwise
                    uialert(app.UIFigure, 'Please select a system first', 'Error');
                    return;
            end

            % ===== Display parameters in table =====
            app.ResultsTable.ColumnName = {'Parameter', 'Value'};

            data = params;

            % ===== Add controller limits (important) =====
            for i = 1:numel(gainNames)
                data = [data; {['Max ' gainNames{i}], VarMax(i)}];
            end

            % ===== Add general info =====
            data = [data;
                {'Controller', controllerType};
                {'Population', nPop};
                {'Max Iterations', MaxIt};
                {'Number of Gains', numel(gainNames)}
                ];

            % ===== Show gain names =====
            if numel(gainNames) >= 1
                data = [data; {'Gain 1', gainNames{1}}];
            end

            if numel(gainNames) >= 2
                data = [data; {'Gain 2', gainNames{2}}];
            end

            if numel(gainNames) >= 3
                data = [data; {'Gain 3', gainNames{3}}];
            end

            app.ResultsTable.Data = data;

            % ===== Dummy system response plot =====
            t = linspace(0,10,100);
            y = sin(t);

            plot(app.System_Response, t, y, 'LineWidth',2)
            grid(app.System_Response,'on')
            title(app.System_Response, 'System Response')

            % ===== Dummy cost plot =====
            cost = exp(-0.3*t);

            plot(app.Cost, t, cost, 'LineWidth',2)
            grid(app.Cost,'on')
            title(app.Cost, 'Cost Evolution')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.System_Response = uiaxes(app.UIFigure);
            title(app.System_Response, 'System')
            xlabel(app.System_Response, 'X')
            ylabel(app.System_Response, 'Y')
            zlabel(app.System_Response, 'Z')
            app.Cost = uiaxes(app.UIFigure);
            title(app.Cost, 'Cost')
            xlabel(app.Cost, 'X')
            ylabel(app.Cost, 'Y')
            zlabel(app.Cost, 'Z')
            app.BlackHoleOptimizerLabel = uilabel(app.UIFigure);
            app.BlackHoleOptimizerLabel.Text = '   Black Hole Optimizer';
            app.SystemSelectionPanel = uipanel(app.UIFigure);
            app.SystemSelectionPanel.Title = 'System Selection';
            app.SelectSystemDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.SelectSystemDropDownLabel.Text = 'Select System';
            app.SelectSystemDropDown = uidropdown(app.SystemSelectionPanel);
            app.SelectSystemDropDown.Items = {'', 'Ball & Beam', 'Cruise Control', 'Motor Speed Control'};
            app.SelectSystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectSystemDropDownValueChanged, true);
            app.SelectSystemDropDown.Value = '';
            app.TypeofControllerDropDownLabel = uilabel(app.SystemSelectionPanel);
            app.TypeofControllerDropDownLabel.Text = 'Type of Controller';
            app.TypeofControllerDropDown = uidropdown(app.SystemSelectionPanel);
            app.TypeofControllerDropDown.Items = {'PI', 'PD', 'PID'};
            app.TypeofControllerDropDown.Value = 'PI';
            app.OptimizationSettingsPanel = uipanel(app.UIFigure);
            app.OptimizationSettingsPanel.Title = 'Optimization Settings';
            app.PopulationNumberofStarsLabel = uilabel(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsLabel.Text = 'Population (Number of Stars)';
            app.PopulationNumberofStarsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.PopulationNumberofStarsSpinner.Step = 5;
            app.PopulationNumberofStarsSpinner.Limits = [0 Inf];
            app.MaxIterationsSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinnerLabel.Text = 'Max Iterations';
            app.MaxIterationsSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxIterationsSpinner.Step = 5;
            app.MaxIterationsSpinner.Limits = [0 Inf];
            app.MaxKpSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKpSpinnerLabel.Text = 'Max Kp';
            app.MaxKpSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKpSpinner.Step = 10;
            app.MaxKpSpinner.Limits = [0 Inf];
            app.MaxKpSpinner.Value = 500;
            app.MaxKiSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKiSpinnerLabel.Text = 'Max Ki';
            app.MaxKiSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKiSpinner.Step = 100;
            app.MaxKiSpinner.Limits = [0 Inf];
            app.MaxKiSpinner.Value = 500;
            app.MaxKdSpinnerLabel = uilabel(app.OptimizationSettingsPanel);
            app.MaxKdSpinnerLabel.Text = 'Max Kd';
            app.MaxKdSpinner = uispinner(app.OptimizationSettingsPanel);
            app.MaxKdSpinner.Step = 10;
            app.MaxKdSpinner.Limits = [0 Inf];
            app.MaxKdSpinner.Value = 500;
            app.RunButton = uibutton(app.OptimizationSettingsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResultsTable = uitable(app.UIFigure);
            app.ResultsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ResultsTable.RowName = {};
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.CruiseParametersPanel = uipanel(app.ParametersPanel);
            app.CruiseParametersPanel.Visible = 'off';
            app.mSpinner_2Label = uilabel(app.CruiseParametersPanel);
            app.mSpinner_2Label.Text = 'm';
            app.m_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.bSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.b_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel = uilabel(app.CruiseParametersPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.Speed_cruiseSpinner = uispinner(app.CruiseParametersPanel);
            app.Speed_cruiseSpinner.Value = 1;
            app.BandBParametersPanel = uipanel(app.ParametersPanel);
            app.BandBParametersPanel.Visible = 'off';
            app.mSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.mSpinnerLabel.Text = 'm';
            app.m_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.gSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.gSpinnerLabel.Text = 'g';
            app.g_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.g_b_and_bSpinner.Value = 9.8;
            app.dSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.dSpinnerLabel.Text = 'd';
            app.d_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.RSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.RSpinnerLabel.Text = 'R';
            app.R_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.LSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.LSpinnerLabel.Text = 'L';
            app.L_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitudeSpinnerLabel = uilabel(app.BandBParametersPanel);
            app.StepAmplitudeSpinnerLabel.Text = 'Step Amplitude';
            app.StepAmplitude_b_and_bSpinner = uispinner(app.BandBParametersPanel);
            app.StepAmplitude_b_and_bSpinner.Value = 1;
            app.MotorParametersPanel = uipanel(app.ParametersPanel);
            app.MotorParametersPanel.Visible = 'off';
            app.RSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.RSpinner_2Label.Text = 'R';
            app.R_motorSpinner = uispinner(app.MotorParametersPanel);
            app.LSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.LSpinner_2Label.Text = 'L';
            app.L_motorSpinner = uispinner(app.MotorParametersPanel);
            app.mSpinner_2Label_2 = uilabel(app.MotorParametersPanel);
            app.mSpinner_2Label_2.Text = 'J';
            app.J_motorSpinner = uispinner(app.MotorParametersPanel);
            app.bSpinner_2Label = uilabel(app.MotorParametersPanel);
            app.bSpinner_2Label.Text = 'b';
            app.b_motorSpinner = uispinner(app.MotorParametersPanel);
            app.KSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.KSpinnerLabel.Text = 'K';
            app.K_motorSpinner = uispinner(app.MotorParametersPanel);
            app.RPMSpinnerLabel = uilabel(app.MotorParametersPanel);
            app.RPMSpinnerLabel.Text = 'RPM';
            app.RPM_motorSpinner = uispinner(app.MotorParametersPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end